classdef DELLO_Review_Pannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DELLO_ReviewUIFigure            matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ImportNameFileButton            matlab.ui.control.Button
        ImportIndividualPositionButton  matlab.ui.control.Button
        ImportMNIPositionButton         matlab.ui.control.Button
        ExportSlicesButton              matlab.ui.control.Button
        ExportElectrodeInfoButton       matlab.ui.control.Button
        UITableElelctrode               matlab.ui.control.Table
        ImportImageFileButton           matlab.ui.control.Button
        AAL3EditField                   matlab.ui.control.EditField
        YEO7EditField                   matlab.ui.control.EditField
        GWEditField                     matlab.ui.control.EditField
        GreyWhiteMatterLabel            matlab.ui.control.Label
        AAL3AtlasLabel                  matlab.ui.control.Label
        Yeo7AtlasLabel                  matlab.ui.control.Label
        EditFieldStatus1                matlab.ui.control.EditField
        EditFieldStatus2                matlab.ui.control.EditField
        EditFieldStatus3                matlab.ui.control.EditField
        EditFieldStatus4                matlab.ui.control.EditField
    end


    properties (Access = private)
        ElectrodeNames
        ElectrodePos
        ElectrodeMNIPos
        
        IndividualMRI
        
        AAL3Matrix
        AAL3Labels
        
        Yeo7Matrix
        Yeo7Labels
        
        GreyMatrix
        
        DepthElectrodeObj
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DepthObj)
              app.DepthElectrodeObj = DepthObj;
            
            
        end

        % Button pushed function: ImportNameFileButton
        function ImportNameFileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            ElectrodeNameFile = [path,file];
            app.ElectrodeNames = importdata(ElectrodeNameFile);
            app.UITableElelctrode.Data = app.ElectrodeNames;
            app.EditFieldStatus1.Value = 'OK';
            app.DELLO_ReviewUIFigure.Visible = 'Off';
            app.DELLO_ReviewUIFigure.Visible = 'On';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DELLO_ReviewUIFigure = uifigure;
            app.DELLO_ReviewUIFigure.Name = 'DELLO_Review';
            app.UIAxes = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes, 'SliceView1')
            app.UIAxes_2 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_2, 'SliceView2')
            app.UIAxes_3 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_3, 'SliceView3')
            app.ImportNameFileButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportNameFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportNameFileButtonPushed, true);
            app.ImportNameFileButton.Text = 'Import Name File';
            app.ImportIndividualPositionButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportIndividualPositionButton.Text = 'Import Individual Position';
            app.ImportMNIPositionButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportMNIPositionButton.Text = 'Import MNI Position';
            app.ExportSlicesButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ExportSlicesButton.Text = 'Export Slices';
            app.ExportElectrodeInfoButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ExportElectrodeInfoButton.Text = 'Export Electrode Info';
            app.UITableElelctrode = uitable(app.DELLO_ReviewUIFigure);
            app.UITableElelctrode.ColumnName = {'Electrode'};
            app.UITableElelctrode.RowName = {};
            app.ImportImageFileButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportImageFileButton.Text = 'Import Image File';
            app.AAL3EditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.YEO7EditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.GWEditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.GreyWhiteMatterLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.GreyWhiteMatterLabel.Text = 'Grey & White Matter';
            app.AAL3AtlasLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.AAL3AtlasLabel.Text = 'AAL3 Atlas';
            app.Yeo7AtlasLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.Yeo7AtlasLabel.Text = 'Yeo 7 Atlas';
            app.EditFieldStatus1 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus1.Value = 'Pending';
            app.EditFieldStatus2 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus2.Value = 'Pending';
            app.EditFieldStatus3 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus3.Value = 'Pending';
            app.EditFieldStatus4 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus4.Value = 'Pending';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DELLO_Review_Pannel(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DELLO_ReviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DELLO_ReviewUIFigure)
        end
    end
end